classdef Proyecto_Final_bien < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        LoadButton    matlab.ui.control.Button
        GrabandoL     matlab.ui.control.Label
        Cancion_name  matlab.ui.control.Label
        CancinLabel   matlab.ui.control.Label
        Button        matlab.ui.control.Button
        Image         matlab.ui.control.Image
    end

    properties(Access=public)
        num_canciones
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.GrabandoL.Text="Grabando...";
            fs=44100;
            duracion = 7; % Duración de la grabación en segu
            % Crear el objeto de grabación
            recorderObj = audiorecorder(fs, 16, 1);
      
            % Iniciar la grabación
            recordblocking(recorderObj, duracion);
        
            % Obtener los datos grabados
            audioData = getaudiodata(recorderObj);
        
            % Detener y restablecer el objeto de grabación
            stop(recorderObj);
        % Mostrar información en la etiqueta
            app.GRABARLabel.Visible="off";
            app.GRABARLabel.Text = "GRABACION COMPLETA";
            app.GRABARLabel.Visible="on";


        % Aquí puedes procesar o guardar los datos grabados (audioData)

         % Por ejemplo, un filtro pasa bajos
            fc = 4000; % Frecuencia de corte del filtro
            orden = 5;% Orden del filtro
            filtroPasaBajos = designfilt('lowpassfir', 'CutoffFrequency', fc, 'FilterOrder', orden, 'SampleRate', fs);
            audioFiltrado = filter(filtroPasaBajos, audioData);
        %audioFiltrado=2.*audioFiltrado;
        for i=1:length(app.num_canciones)

        end
    
        % Ruta de la carpeta con las canciones


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            ruta_canciones = 'D:\Repositorios\Canciones';
            archivos_canciones = dir(fullfile(ruta_canciones, '*.wav'));
            import Cancion
            app.num_canciones=length(archivos_canciones);
            lista_canciones=cell(1:app.num_canciones);
            for i=1:app.num_canciones
            lista_canciones(i)=Cancion(archivo_canciones(i),ruta_canciones,5);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CancinLabel = uilabel(app.UIFigure);
            app.CancinLabel.Text = 'Canción:';
            app.Cancion_name = uilabel(app.UIFigure);
            app.Cancion_name.Text = '';
            app.GrabandoL = uilabel(app.UIFigure);
            app.GrabandoL.Text = '';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto_Final_bien

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end